classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BPMLabel_3                  matlab.ui.control.Label
        Label_15                    matlab.ui.control.Label
        Label_14                    matlab.ui.control.Label
        Label_13                    matlab.ui.control.Label
        Label_12                    matlab.ui.control.Label
        Label_11                    matlab.ui.control.Label
        Label_10                    matlab.ui.control.Label
        Label_9                     matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        FREQCOUPhzEditField_2       matlab.ui.control.NumericEditField
        FREQCOUPhzEditField_2Label  matlab.ui.control.Label
        ZoomSpectroButton_2         matlab.ui.control.Button
        ZoomfiltreButton_2          matlab.ui.control.Button
        ExportGrapheButton_2        matlab.ui.control.Button
        SPECTREAMPLITUDEButton_2    matlab.ui.control.Button
        SPETROGRAMButton_2          matlab.ui.control.Button
        RESETButton_2               matlab.ui.control.Button
        FILTREButton_2              matlab.ui.control.Button
        LOADButton_2                matlab.ui.control.Button
        ZoomSpectroButton           matlab.ui.control.Button
        ZoomfiltreButton            matlab.ui.control.Button
        ExportGrapheButton          matlab.ui.control.Button
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        BPMLabel_2                  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        FREQCOUPhzEditField         matlab.ui.control.NumericEditField
        FREQCOUPhzEditFieldLabel    matlab.ui.control.Label
        SPECTREAMPLITUDEButton      matlab.ui.control.Button
        SPETROGRAMButton            matlab.ui.control.Button
        RESETButton                 matlab.ui.control.Button
        FILTREButton                matlab.ui.control.Button
        LOADButton                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
global val;  
global interval;
global sampling_rate;
infoName = uigetfile('*.info', 'Open file .info');
matName = uigetfile('*.mat', 'Open file .mat');% only image Bitmap
Octave = exist('OCTAVE_VERSION');
load(matName);
fid = fopen(infoName, 'rt');
fgetl(fid);
fgetl(fid);
fgetl(fid);
[freqint] = sscanf(fgetl(fid), 'Sampling frequency: %f Hz  Sampling interval: %f sec');
interval = freqint(2);
sampling_rate = freqint(1);
fgetl(fid);

if(Octave)
    for i = 1:size(val, 1)
       R = strsplit(fgetl(fid), char(9));
       signal{i} = R{2};
       gain(i) = str2num(R{3});
       base(i) = str2num(R{4});
       units{i} = R{5};
    end
else
    for i = 1:size(val, 1)
      [row(i), signal(i), gain(i), base(i), units(i)]=strread(fgetl(fid),'%d%s%f%f%s','delimiter','\t');
    end
end

fclose(fid);
val(val==-32768) = NaN;

for i = 1:size(val, 1)
    val(i, :) = (val(i, :) - base(i)) / gain(i);
end

x = (1:size(val, 2)) * interval;
plot(app.UIAxes,x', val');
xlabel(app.UIAxes,'Temps (s)');
ylabel(app.UIAxes,'Tension (v)');
title(app.UIAxes,'Signal ECG');


for i = 1:length(signal)
    labels{i} = strcat(signal{i}, ' (', units{i}, ')'); 
end

window_size = 0.5; % Taille de la fenêtre de recherche (en secondes)
    threshold = 0.4; % Seuil de détection des pics

    % Normalisation du signal
    normalized_signal = normalize(val);

    % Détection des pics R
    [~,locs] = findpeaks(normalized_signal, 'MinPeakHeight', threshold, 'MinPeakDistance', round(window_size*sampling_rate));


    % Calcul de la fréquence cardiaque
    heart_rate = 60 / (mean(diff(locs)) / sampling_rate);
    app.Label.Text = num2str(heart_rate);


        end

        % Button pushed function: FILTREButton
        function FILTREButtonPushed(app, event)
            global val;
            global interval;
            global sampling_rate;
            global filtered_signal;
            order = 4;  % Ordre du filtre Butterworth
    cutoff_frequency = 20;
    normalized_cutoff_frequency = cutoff_frequency / (0.5 * sampling_rate);
    [b, a] = butter(order, normalized_cutoff_frequency, 'low');
    
    % Filtrage du signal
    filtered_signal = filtfilt(b, a, val);
    
x = (1:size(filtered_signal, 2)) * interval;
plot(app.UIAxes, x, filtered_signal);
xlabel(app.UIAxes,'Temps (s)');
ylabel(app.UIAxes,'Tension (v)');
title(app.UIAxes,'Signal Filtré');


% Calcul de l'indice de variabilité de la fréquence cardiaque (HRV)
    [~,locs] = findpeaks(filtered_signal, 'MinPeakDistance', sampling_rate); % Détection des pics R
    rr_intervals = diff(locs) / sampling_rate; % Calcul des intervalles entre les pics R (en secondes)
    hrv = std(rr_intervals); % Calcul de l'écart-type des intervalles RR

    % Détection de grandes variations de fréquence
    threshold = 0.2; % Seuil pour détecter les grandes variations (à ajuster selon vos besoins)
    has_large_variations = hrv > threshold;
if has_large_variations
        app.Label_2.Text = 'Le signal présente de grandes variations de fréquence.';

else
    app.Label_2.Text ='Le signal ne présente pas de grandes variations de fréquence.';
end
% Détection des grandes variations de fréquence

% Calcul de l'écart-type du signal
    amplitude_std = std(filtered_signal);

    % Détection de variations d'amplitude
    threshold = 0.1; % Seuil pour détecter les variations d'amplitude (à ajuster selon vos besoins)
    has_amplitude_variations = amplitude_std > threshold;
    if has_amplitude_variations
    app.Label_3.Text ='Le signal présente des variations d''amplitude significatives.';
else
    app.Label_3.Text ='Le signal ne présente pas de variations d''amplitude significatives.';
    end
        end

        % Value changed function: FREQCOUPhzEditField
        function FREQCOUPhzEditFieldValueChanged(app, event)
            global val;
            global interval;
            global sampling_rate;
            global filtered_signal;
            order = 4;  % Ordre du filtre Butterworth
    cutoff_frequency = app.FREQCOUPhzEditField.Value;
    normalized_cutoff_frequency = cutoff_frequency / (0.5 * sampling_rate);
    [b, a] = butter(order, normalized_cutoff_frequency, 'low');
    
    % Filtrage du signal
    filtered_signal = filtfilt(b, a, val);
    
x = (1:size(filtered_signal, 2)) * interval;
plot(app.UIAxes, x, filtered_signal);
title(app.UIAxes,'Signal filtré');

        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            global val;
            global interval;
            x = (1:size(val, 2)) * interval;
plot(app.UIAxes,x', val');
xlabel(app.UIAxes,'Temps (s)');
ylabel(app.UIAxes,'Tension (v)');
title(app.UIAxes,'Signal ECG');

        end

        % Button pushed function: SPECTREAMPLITUDEButton
        function SPECTREAMPLITUDEButtonPushed(app, event)
            global sampling_rate;
            global filtered_signal;
            n = length(filtered_signal);
    frequency = (0:n/2-1) * (sampling_rate / n);
    amplitude = abs(fft(filtered_signal)/n);
    
    % Retourne seulement la moitié positive du spectre (sans symétrie négative)
    frequency = frequency(1:floor(n/2));
    amplitude = amplitude(1:floor(n/2));

% Tracé du spectre de fréquence
plot(app.UIAxes,frequency', amplitude');
xlabel(app.UIAxes,'Frequency (Hz)');
ylabel(app.UIAxes,'Amplitude');
title(app.UIAxes,'Spectre d amplitude');

        end

        % Button pushed function: SPETROGRAMButton
        function SPETROGRAMButtonPushed(app, event)
            global sampling_rate;
            global filtered_signal;
            window_length = round(sampling_rate * 0.2);  % Longueur de la fenêtre en échantillons (0.2 seconde dans cet exemple)
    overlap = round(window_length / 2);  % Recouvrement entre les fenêtres (50% dans cet exemple)
    
    % Calcul de la STFT
    [S, F, T] = spectrogram(filtered_signal, hamming(window_length), overlap, [], sampling_rate);
    
    % Tracé du spectrogramme
    imagesc(app.UIAxes,T, F, 20*log10(abs(S)));
    set(app.UIAxes, 'YDir', 'normal');
    xlabel(app.UIAxes,'Time (sec)');
    ylabel(app.UIAxes,'Frequency (Hz)');
    title(app.UIAxes,'Spectrogram');
        end

        % Button pushed function: ExportGrapheButton
        function ExportGrapheButtonPushed(app, event)
         exportgraphics(app.UIAxes, 'results.pdf', 'ContentType', 'image');

        end

        % Button pushed function: ZoomfiltreButton
        function ZoomfiltreButtonPushed(app, event)
            global filtered_signal;
            global interval;
            x = (1:400) * interval;
            J = filtered_signal(:, 1:400);
            plot(app.UIAxes,x', J');
            title(app.UIAxes,'Zoom signal filtré');
        end

        % Button pushed function: ZoomSpectroButton
        function ZoomSpectroButtonPushed(app, event)
            global sampling_rate;
            global filtered_signal;
            L = filtered_signal(:, 1:400);
            window_length = round(sampling_rate * 0.2);  % Longueur de la fenêtre en échantillons (0.2 seconde dans cet exemple)
    overlap = round(window_length / 2);  % Recouvrement entre les fenêtres (50% dans cet exemple)
    
    % Calcul de la STFT
    [S, F, T] = spectrogram(L, hamming(window_length), overlap, [], sampling_rate);
    
    % Tracé du spectrogramme
    imagesc(app.UIAxes,T, F, 20*log10(abs(S)));
    set(app.UIAxes, 'YDir', 'normal');
    xlabel(app.UIAxes,'Time (sec)');
    ylabel(app.UIAxes,'Frequency (Hz)');
    title(app.UIAxes,'Zoom Spectrogram');
        end

        % Button pushed function: LOADButton_2
        function LOADButton_2Pushed(app, event)
global val2;  
global interval2;
global sampling_rate2;
infoName = uigetfile('*.info', 'Open file .info');
matName = uigetfile('*.mat', 'Open file .mat');% only image Bitmap
Octave = exist('OCTAVE_VERSION');
load(matName);
fid = fopen(infoName, 'rt');
fgetl(fid);
fgetl(fid);
fgetl(fid);
[freqint] = sscanf(fgetl(fid), 'Sampling frequency: %f Hz  Sampling interval: %f sec');
interval2 = freqint(2);
sampling_rate2 = freqint(1);
fgetl(fid);

if(Octave)
    for i = 1:size(val2, 1)
       R = strsplit(fgetl(fid), char(9));
       signal{i} = R{2};
       gain(i) = str2num(R{3});
       base(i) = str2num(R{4});
       units{i} = R{5};
    end
else
    for i = 1:size(val2, 1)
      [row(i), signal(i), gain(i), base(i), units(i)]=strread(fgetl(fid),'%d%s%f%f%s','delimiter','\t');
    end
end

fclose(fid);
val2(val2==-32768) = NaN;

for i = 1:size(val2, 1)
    val2(i, :) = (val2(i, :) - base(i)) / gain(i);
end

x = (1:size(val2, 2)) * interval2;
plot(app.UIAxes2,x', val2');
xlabel(app.UIAxes2,'Temps (s)');
ylabel(app.UIAxes2,'Tension (v)');
title(app.UIAxes2,'signal ECG');

for i = 1:length(signal)
    labels{i} = strcat(signal{i}, ' (', units{i}, ')'); 
end

window_size = 0.5; % Taille de la fenêtre de recherche (en secondes)
    threshold = 0.4; % Seuil de détection des pics

    % Normalisation du signal
    normalized_signal = normalize(val2);

    % Détection des pics R
    [~,locs] = findpeaks(normalized_signal, 'MinPeakHeight', threshold, 'MinPeakDistance', round(window_size*sampling_rate2));


    % Calcul de la fréquence cardiaque
    heart_rate = 60 / (mean(diff(locs)) / sampling_rate2);
    app.Label_15.Text = num2str(heart_rate);


        end

        % Button pushed function: FILTREButton_2
        function FILTREButton_2Pushed(app, event)
            global val2;
            global interval2;
            global sampling_rate2;
            global filtered_signal2;
            order = 4;  % Ordre du filtre Butterworth
    cutoff_frequency = 20;
    normalized_cutoff_frequency = cutoff_frequency / (0.5 * sampling_rate2);
    [b, a] = butter(order, normalized_cutoff_frequency, 'low');
    
    % Filtrage du signal
    filtered_signal2 = filtfilt(b, a, val2);
    
x = (1:size(filtered_signal2, 2)) * interval2;
plot(app.UIAxes2, x, filtered_signal2);
xlabel(app.UIAxes2,'Temps (s)');
ylabel(app.UIAxes2,'Tension (v)');
title(app.UIAxes2,'signal filtré');


        end

        % Value changed function: FREQCOUPhzEditField_2
        function FREQCOUPhzEditField_2ValueChanged(app, event)
            global val2;
            global interval2;
            global sampling_rate2;
            global filtered_signal2;
            order = 4;  % Ordre du filtre Butterworth
    cutoff_frequency = app.FREQCOUPhzEditField_2.Value;
    normalized_cutoff_frequency = cutoff_frequency / (0.5 * sampling_rate2);
    [b, a] = butter(order, normalized_cutoff_frequency, 'low');
    
    % Filtrage du signal
    filtered_signal2 = filtfilt(b, a, val2);
    
x = (1:size(filtered_signal2, 2)) * interval2;
plot(app.UIAxes2, x, filtered_signal2);
title(app.UIAxes2,'signal filtré');
        end

        % Button pushed function: RESETButton_2
        function RESETButton_2Pushed(app, event)
            global val2;
            global interval2;
            x = (1:size(val2, 2)) * interval2;
plot(app.UIAxes2,x', val2');
xlabel(app.UIAxes2,'Temps (s)');
ylabel(app.UIAxes2,'Tension (v)');
title(app.UIAxes2,'signal ECG');
        end

        % Button pushed function: SPECTREAMPLITUDEButton_2
        function SPECTREAMPLITUDEButton_2Pushed(app, event)
            global sampling_rate2;
            global filtered_signal2;
            n = length(filtered_signal2);
    frequency = (0:n/2-1) * (sampling_rate2 / n);
    amplitude = abs(fft(filtered_signal2)/n);
    
    % Retourne seulement la moitié positive du spectre (sans symétrie négative)
    frequency = frequency(1:floor(n/2));
    amplitude = amplitude(1:floor(n/2));

% Tracé du spectre de fréquence
plot(app.UIAxes2,frequency', amplitude');
xlabel(app.UIAxes2,'Frequency (Hz)');
ylabel(app.UIAxes2,'Amplitude');
title(app.UIAxes2,'Spectre D amplitude');
        end

        % Button pushed function: SPETROGRAMButton_2
        function SPETROGRAMButton_2Pushed(app, event)
            global sampling_rate2;
            global filtered_signal2;
            window_length = round(sampling_rate2 * 0.2);  % Longueur de la fenêtre en échantillons (0.2 seconde dans cet exemple)
    overlap = round(window_length / 2);  % Recouvrement entre les fenêtres (50% dans cet exemple)
    
    % Calcul de la STFT
    [S, F, T] = spectrogram(filtered_signal2, hamming(window_length), overlap, [], sampling_rate2);
    
    % Tracé du spectrogramme
    imagesc(app.UIAxes2,T, F, 20*log10(abs(S)));
    set(app.UIAxes2, 'YDir', 'normal');
    xlabel(app.UIAxes2,'Time (sec)');
    ylabel(app.UIAxes2,'Frequency (Hz)');
    title(app.UIAxes2,'Spectrogram');
        end

        % Button pushed function: ExportGrapheButton_2
        function ExportGrapheButton_2Pushed(app, event)
            exportgraphics(app.UIAxes2, 'results2.pdf', 'ContentType', 'image');
        end

        % Button pushed function: ZoomfiltreButton_2
        function ZoomfiltreButton_2Pushed(app, event)
            global filtered_signal2;
            global interval2;
            x = (1:400) * interval2;
            J = filtered_signal2(:, 1:400);
            plot(app.UIAxes2,x', J');
            xlabel(app.UIAxes2,'Temps (s)');
            ylabel(app.UIAxes2,'Tension (v)');
            title(app.UIAxes2,'Zoom signal filtré');
        end

        % Button pushed function: ZoomSpectroButton_2
        function ZoomSpectroButton_2Pushed(app, event)
            global sampling_rate2;
            global filtered_signal2;
            L = filtered_signal2(:, 1:400);
            window_length = round(sampling_rate2 * 0.2);  % Longueur de la fenêtre en échantillons (0.2 seconde dans cet exemple)
    overlap = round(window_length / 2);  % Recouvrement entre les fenêtres (50% dans cet exemple)
    
    % Calcul de la STFT
    [S, F, T] = spectrogram(L, hamming(window_length), overlap, [], sampling_rate2);
    
    % Tracé du spectrogramme
    imagesc(app.UIAxes2,T, F, 20*log10(abs(S)));
    set(app.UIAxes2, 'YDir', 'normal');
    xlabel(app.UIAxes2,'Time (sec)');
    ylabel(app.UIAxes2,'Frequency (Hz)');
    title(app.UIAxes2,'Zoom Spectrogram');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.FILTREButton = uibutton(app.UIFigure, 'push');
            app.FILTREButton.ButtonPushedFcn = createCallbackFcn(app, @FILTREButtonPushed, true);
            app.FILTREButton.Text = 'FILTRE';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.SPETROGRAMButton = uibutton(app.UIFigure, 'push');
            app.SPETROGRAMButton.ButtonPushedFcn = createCallbackFcn(app, @SPETROGRAMButtonPushed, true);
            app.SPETROGRAMButton.Text = 'SPETROGRAM';
            app.SPECTREAMPLITUDEButton = uibutton(app.UIFigure, 'push');
            app.SPECTREAMPLITUDEButton.ButtonPushedFcn = createCallbackFcn(app, @SPECTREAMPLITUDEButtonPushed, true);
            app.SPECTREAMPLITUDEButton.Text = 'SPECTRE AMPLITUDE';
            app.FREQCOUPhzEditFieldLabel = uilabel(app.UIFigure);
            app.FREQCOUPhzEditFieldLabel.Text = 'FREQ COUP (hz)';
            app.FREQCOUPhzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FREQCOUPhzEditField.ValueChangedFcn = createCallbackFcn(app, @FREQCOUPhzEditFieldValueChanged, true);
            app.FREQCOUPhzEditField.Value = 20;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.BPMLabel_2 = uilabel(app.UIFigure);
            app.BPMLabel_2.Text = 'BPM :';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.ExportGrapheButton = uibutton(app.UIFigure, 'push');
            app.ExportGrapheButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGrapheButtonPushed, true);
            app.ExportGrapheButton.Text = 'ExportGraphe';
            app.ZoomfiltreButton = uibutton(app.UIFigure, 'push');
            app.ZoomfiltreButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomfiltreButtonPushed, true);
            app.ZoomfiltreButton.Text = 'Zoom ( filtre)';
            app.ZoomSpectroButton = uibutton(app.UIFigure, 'push');
            app.ZoomSpectroButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomSpectroButtonPushed, true);
            app.ZoomSpectroButton.Text = 'Zoom (Spectro)';
            app.LOADButton_2 = uibutton(app.UIFigure, 'push');
            app.LOADButton_2.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_2Pushed, true);
            app.LOADButton_2.Text = 'LOAD';
            app.FILTREButton_2 = uibutton(app.UIFigure, 'push');
            app.FILTREButton_2.ButtonPushedFcn = createCallbackFcn(app, @FILTREButton_2Pushed, true);
            app.FILTREButton_2.Text = 'FILTRE';
            app.RESETButton_2 = uibutton(app.UIFigure, 'push');
            app.RESETButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESETButton_2Pushed, true);
            app.RESETButton_2.Text = 'RESET';
            app.SPETROGRAMButton_2 = uibutton(app.UIFigure, 'push');
            app.SPETROGRAMButton_2.ButtonPushedFcn = createCallbackFcn(app, @SPETROGRAMButton_2Pushed, true);
            app.SPETROGRAMButton_2.Text = 'SPETROGRAM';
            app.SPECTREAMPLITUDEButton_2 = uibutton(app.UIFigure, 'push');
            app.SPECTREAMPLITUDEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SPECTREAMPLITUDEButton_2Pushed, true);
            app.SPECTREAMPLITUDEButton_2.Text = 'SPECTRE AMPLITUDE';
            app.ExportGrapheButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportGrapheButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportGrapheButton_2Pushed, true);
            app.ExportGrapheButton_2.Text = 'ExportGraphe';
            app.ZoomfiltreButton_2 = uibutton(app.UIFigure, 'push');
            app.ZoomfiltreButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZoomfiltreButton_2Pushed, true);
            app.ZoomfiltreButton_2.Text = 'Zoom ( filtre)';
            app.ZoomSpectroButton_2 = uibutton(app.UIFigure, 'push');
            app.ZoomSpectroButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZoomSpectroButton_2Pushed, true);
            app.ZoomSpectroButton_2.Text = 'Zoom (Spectro)';
            app.FREQCOUPhzEditField_2Label = uilabel(app.UIFigure);
            app.FREQCOUPhzEditField_2Label.Text = 'FREQ COUP (hz)';
            app.FREQCOUPhzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FREQCOUPhzEditField_2.ValueChangedFcn = createCallbackFcn(app, @FREQCOUPhzEditField_2ValueChanged, true);
            app.FREQCOUPhzEditField_2.Value = 20;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '|';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '|';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '|';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '|';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '|';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '|';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '|';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '|';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '|';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '|';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '|';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '0';
            app.BPMLabel_3 = uilabel(app.UIFigure);
            app.BPMLabel_3.Text = 'BPM :';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end